classdef chessmatch < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        moveFeedbackLabel              matlab.ui.control.Label
        PromotionDropDown              matlab.ui.control.DropDown
        PromotionDropDownLabel         matlab.ui.control.Label
        row_coordinates                matlab.ui.control.Label
        column_coordinates             matlab.ui.control.Label
        black_rook_a                   matlab.ui.control.Image
        black_knight_b                 matlab.ui.control.Image
        black_bishop_c                 matlab.ui.control.Image
        black_queen                    matlab.ui.control.Image
        black_king                     matlab.ui.control.Image
        black_bishop_f                 matlab.ui.control.Image
        black_knight_g                 matlab.ui.control.Image
        black_rook_h                   matlab.ui.control.Image
        black_pawn_a                   matlab.ui.control.Image
        black_pawn_b                   matlab.ui.control.Image
        black_pawn_c                   matlab.ui.control.Image
        black_pawn_d                   matlab.ui.control.Image
        black_pawn_e                   matlab.ui.control.Image
        black_pawn_f                   matlab.ui.control.Image
        black_pawn_g                   matlab.ui.control.Image
        black_pawn_h                   matlab.ui.control.Image
        white_rook_a                   matlab.ui.control.Image
        white_knight_b                 matlab.ui.control.Image
        white_bishop_c                 matlab.ui.control.Image
        white_king                     matlab.ui.control.Image
        white_queen                    matlab.ui.control.Image
        white_bishop_f                 matlab.ui.control.Image
        white_knight_g                 matlab.ui.control.Image
        white_rook_h                   matlab.ui.control.Image
        white_pawn_a                   matlab.ui.control.Image
        white_pawn_b                   matlab.ui.control.Image
        white_pawn_c                   matlab.ui.control.Image
        white_pawn_d                   matlab.ui.control.Image
        white_pawn_e                   matlab.ui.control.Image
        white_pawn_f                   matlab.ui.control.Image
        white_pawn_g                   matlab.ui.control.Image
        white_pawn_h                   matlab.ui.control.Image
        WhoMovesLabel                  matlab.ui.control.Label
        chessboard                     matlab.ui.control.Image
        DestinationEditField           matlab.ui.control.EditField
        DestinationEditFieldLabel      matlab.ui.control.Label
        MoveButton                     matlab.ui.control.Button
        PieceCoordinateEditField       matlab.ui.control.EditField
        PieceCoordinateEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        board % the 8x8 string array of the board
        game % game state variable (0=keep playing, 1=white won, 2=black won)
        turn % whos turn it is (white=1  black=2)
        turn_color % "w"=white turn, "b"=black turn
        alpha_num % The coordinate of the piece/destination
        ca % image array of all the pieces
        row % Row coordinate array
        column % Column coordinate array
        valid % Boolean T/F, whether valid input notation
        legality % Boolean T/F, whether the move is legal
        whiteCastle % white initiated castling
        blackCastle % black initiated castling
        whiteCastlePrivilege_L % Ability for white to castle left (starts as true in startupFnc)
        whiteCastlePrivilege_R % Ability for white to castle right (starts as true in startupFnc)
        blackCastlePrivilege_L % Ability for black to castle left (starts as true in startupFnc)
        blackCastlePrivilege_R % Ability for black to castle right (starts as true in startupFnc)
    end


    methods (Access = public)
     
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function change_whoMovesLabel(app)
            label = app.WhoMovesLabel.Text;
            if label == "White to Move"
                label = "Black to Move";
            elseif label == "Black to Move"
                label = "White to Move";
            end
            app.WhoMovesLabel.Text = label;
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function setup_board(app)
            % Purpose: Sets up a fresh board at the beginning of the game
            app.board = ["br" "bn" "bb" "bq" "bk" "bb" "bn" "br";
                        "bp" "bp" "bp" "bp" "bp" "bp" "bp" "bp";
                        "0" "0" "0" "0" "0" "0" "0" "0";
                        "0" "0" "0" "0" "0" "0" "0" "0";
                        "0" "0" "0" "0" "0" "0" "0" "0";
                        "0" "0" "0" "0" "0" "0" "0" "0";
                        "wp" "wp" "wp" "wp" "wp" "wp" "wp" "wp";
                        "wr" "wn" "wb" "wq" "wk" "wb" "wn" "wr"];
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function start_game(app)
            % Purpose: starts game by setting game to be equal to true
            app.game = 0;
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function white_starts(app)
            % Purpose: make it white's turn at the start of the game
            % (white=1  black=2)
            app.turn = 1;
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function white_color_starts(app)
            % Purpose   : Set the turn_color to white at match start
            app.turn_color = 'w';
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function castle_privilege(app)
        % Purpose: bestows both black and white castling privileges and
        % sets their actual castling initiative to false
        app.whiteCastlePrivilege_L = true;
        app.whiteCastlePrivilege_R = true;
        app.blackCastlePrivilege_L = true;
        app.blackCastlePrivilege_R = true;
        
        app.whiteCastle = false;
        app.blackCastle = false;
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function alpha_num = read_which_piece(app)
            alpha_num = app.PieceCoordinateEditField.Value;
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function alpha_num = read_destination(app)
            alpha_num = app.DestinationEditField.Value;
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function row = coordinate_to_row(app)
            coordinate = app.alpha_num(2);
            coordinate_num = str2double(coordinate);
            row = 9 - coordinate_num;
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function column = coordinate_to_column(app)
            alpha = app.alpha_num(1);

            % Converts letter name of column into a number of the column
            if alpha == 'a'
                column = 1;
            elseif alpha == 'b'
                column = 2;
            elseif alpha == 'c'
                column = 3;
            elseif alpha == 'd'
                column = 4;
            elseif alpha == 'e'
                column = 5;
            elseif alpha == 'f'
                column = 6;
            elseif alpha == 'g'
                column = 7;
            elseif alpha == 'h'
                column = 8;
            end
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function movePiece(app,fromr,fromc,tor,toc)
            % Origin square blanked, piece moved to new square
            app.ca{toc,(9-tor)}.Position = [0 0 0 0];
            app.ca{fromc,9-fromr}.Position = [app.column(toc) app.row(9-tor) 50 50];
            app.ca{toc,9-tor} = app.ca{fromc,9-fromr};
            app.ca{fromc,9-fromr} = [];

            if app.board(tor,toc) == "wk"
                app.game = 2; % Black wins
            elseif app.board(tor,toc) == "bk"
                app.game = 1; % White wins
            end
            
            % White Castling Move
            if (app.whiteCastle == true)
                % Castle Left
                if (toc == 3)
                    app.ca{1,1}.Position = [app.column(4) app.row(1) 50 50];
                    app.ca{4,1} = app.ca{1,1};
                    app.ca{1,1} = [];

                    app.board(8,4) = app.board(8,1);
                    app.board(8,1) = "0";

                    app.whiteCastle = false;

                % Castle right
                elseif (toc == 7)
                    app.ca{8,1}.Position = [app.column(6) app.row(1) 50 50];
                    app.ca{6,1} = app.ca{8,1};
                    app.ca{8,1} = [];

                    app.board(8,6) = app.board(8,8);
                    app.board(8,8) = "0";

                    app.whiteCastle = false;
                end

            % Black Castling Move
            elseif (app.blackCastle == true)
                % Castle left
                if (toc == 3)
                    app.ca{1,8}.Position = [app.column(4) app.row(8) 50 50];
                    app.ca{4,8} = app.ca{1,8};
                    app.ca{1,8} = [];

                    app.board(1,4) = app.board(1,1);
                    app.board(1,1) = "0";

                    app.blackCastle = false;

                % Castle right
                elseif (toc == 7)
                    app.ca{8,8}.Position = [app.column(6) app.row(8) 50 50];
                    app.ca{6,8} = app.ca{8,8};
                    app.ca{8,8} = [];

                    app.board(1,6) = app.board(1,8);
                    app.board(1,8) = "0";

                    app.blackCastle = false;
                end
            end

            % Moves piece on the string array
            app.board(tor,toc) = app.board(fromr,fromc);
            app.board(fromr,fromc) = "0";


        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function changeTurn(app)
            % Flips turn value between 1 (white) and 2 (black)
            if app.turn == 1
                app.turn = 2;
            else 
                app.turn = 1;
            end
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function changeTurnColor(app)
            % Flips turn color between 'w' and 'b'
            if app.turn_color == 'w'
                app.turn_color = 'b';
            elseif app.turn_color == 'b'
                app.turn_color = 'w';
            end
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function clearFields(app)
            % Clears the fields where values are entered
            app.PieceCoordinateEditField.Value = '';
            app.DestinationEditField.Value = '';
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function dispInvalidMove(app)
            % Displays invalid move in the move feedback label
            app.moveFeedbackLabel.Text = "Invalid Move Notation";
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function clearFeedback(app)
            % Clears the feedback label
            app.moveFeedbackLabel.Text = "";
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function dispIllegalMove(app)
            % Displays illegal move in the feedback label
            app.moveFeedbackLabel.Text = "Illegal Move";
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function checkValidCoordinate(app)
            % Checks if the piece coordinate has valid notation
            N = length(app.PieceCoordinateEditField.Value);
            if N == 2
                app.valid = true;
                letter = app.PieceCoordinateEditField.Value(1);
                number = app.PieceCoordinateEditField.Value(2);
            else
                app.valid = false;
            end
        
            if (app.valid == true)
                if (letter == 'a') || (letter == 'b') || (letter == 'c') || (letter == 'd') || (letter == 'e') || (letter == 'f') || (letter == 'g') || (letter == 'h')
                    app.valid = true;
                else
                    app.valid = false;
                end
            end
            
            if (app.valid == true) 
                if (number == '1') || (number == '2') || (number == '3') || (number == '4') || (number == '5') || (number == '6') || (number == '7') || (number == '8')
                    app.valid = true;
                else
                    app.valid = false;
                end
            end
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function checkValidDestination(app)
            % Checks if the destination is in valid notation
            N = length(app.DestinationEditField.Value);
            if (app.valid == true)
                if N == 2
                    app.valid = true;
                    letter = app.DestinationEditField.Value(1);
                    number = app.DestinationEditField.Value(2);
                else
                    app.valid = false;
                end
            end

            if (app.valid == true)
                if (letter == 'a') || (letter == 'b') || (letter == 'c') || (letter == 'd') || (letter == 'e') || (letter == 'f') || (letter == 'g') || (letter == 'h')
                    app.valid = true;
                else
                    app.valid = false;
                end
            end
            
            if (app.valid == true) 
                if (number == '1') || (number == '2') || (number == '3') || (number == '4') || (number == '5') || (number == '6') || (number == '7') || (number == '8')
                    app.valid = true;
                else
                    app.valid = false;
                end
            end
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function checkRepetition(app)
            N = length(app.DestinationEditField.Value);
            M = length(app.PieceCoordinateEditField.Value);
            if (M == 2) && (N == 2) && (app.valid == true)
                if app.DestinationEditField.Value == app.PieceCoordinateEditField.Value
                    app.valid = false;
                else
                    app.valid = true;
                end
            end
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function showWin(app)
        % Purpose: Show if either player won and display
            if app.game == 1
                app.WhoMovesLabel.Text = "White Wins!";
            elseif app.game == 2
                app.WhoMovesLabel.Text = "Black Wins!";
            end
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

function checkLegal(app,fromr,fromc,tor,toc)

piece = app.board(fromr,fromc);
piece_char = convertStringsToChars(piece);
piece_color = piece_char(1);
taken = app.board(tor,toc);
taken_char = convertStringsToChars(taken);
taken_color = taken_char(1);

    if app.turn_color ~= piece_color
        app.legality = false;
    elseif app.turn_color == taken_color
        app.legality = false;
    else
    %~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~%    
        app.legality = false;
        % White Pawn Move
        if piece == "wp"
            % Advance 1 up
            if (fromr-1 == tor) && (fromc == toc) && (app.board(tor,toc) == "0")
                app.legality = true;
            % Advance 2 up (only from seventh row)
            elseif (fromr-2 == tor) && (fromc == toc) && (app.board(tor,toc) == "0") && (fromr == 7)
                app.legality = true;
            % Take diagnally
            elseif (fromr-1 == tor) && (fromc-1 == toc) && (app.board(tor,toc) ~= "0")
                app.legality = true;
            % Take diagnally
            elseif (fromr-1 == tor) && (fromc+1 == toc) && (app.board(tor,toc) ~= "0")
                app.legality = true;
            else
                app.legality = false;
            end
        end
    
    
        % Black Pawn Move
        if piece == "bp"
            % Advance 1 up
            if (fromr+1 == tor) && (fromc == toc) && (app.board(tor,toc) == "0")
                app.legality = true;
            % Advance 2 up (only from second row)
            elseif (fromr+2 == tor) && (fromc == toc) && (app.board(tor,toc) == "0") && (fromr == 2)
                app.legality = true;
            % Take diagnally
            elseif (fromr+1 == tor) && (fromc-1 == toc) && (app.board(tor,toc) ~= "0")
                app.legality = true;
            % Take diagnally
            elseif (fromr+1 == tor) && (fromc+1 == toc) && (app.board(tor,toc) ~= "0")
                app.legality = true;
            else
                app.legality = false;
            end
        end
    
        
        % Knight Move
        if piece == "wn" || piece == "bn"
            app.legality = check_knight(app,fromc,fromr,toc,tor);
        end


        % Rook Move
        if piece == "wr" || piece == "br" 
            if toc == fromc
                app.legality = check_updown(app,fromr,tor,toc);
            elseif tor == fromr
                app.legality = check_sideside(app,fromc,toc,tor);
            else
                app.legality = false;
            end

            if (fromr == 8) && (fromc == 1)
                app.whiteCastlePrivilege_L = false;
            elseif (fromr == 8) && (fromc == 8)
                app.whiteCastlePrivilege_R = false;
            elseif (fromr == 1) && (fromc == 1)
                app.blackCastlePrivilege_L = false;
            elseif (fromr == 1) && (fromc == 8)
                app.blackCastlePrivilege_R = false;
            end

        end
    

        % Bishop Move
        if piece == "wb" || piece == "bb"
            if (abs(fromr-tor)) == (abs(fromc-toc))
                if toc>fromc && tor>fromr
                    app.legality = check_SE(app,fromr,tor,toc);
                elseif toc>fromc && tor<fromr
                    app.legality = check_NE(app,fromr,tor,toc);
                elseif toc<fromc && tor<fromr
                    app.legality = check_NW(app,fromr,tor,toc);
                elseif toc<fromc && tor>fromr
                    app.legality = check_SW(app,fromr,tor,toc);
                else
                    app.legality = false;
                end
            else
                app.legality = false;
            end
        end

        
        % Queen Move
        if piece == "wq" || piece == "bq"
            if toc == fromc
                app.legality = check_updown(app,fromr,tor,toc);
            elseif tor == fromr
                app.legality = check_sideside(app,fromc,toc,tor);
            elseif (abs(fromr-tor)) == (abs(fromc-toc))
                if toc>fromc && tor>fromr
                    app.legality = check_SE(app,fromr,tor,toc);
                elseif toc>fromc && tor<fromr
                    app.legality = check_NE(app,fromr,tor,toc);
                elseif toc<fromc && tor<fromr
                    app.legality = check_NW(app,fromr,tor,toc);
                elseif toc<fromc && tor>fromr
                    app.legality = check_SW(app,fromr,tor,toc);
                else
                    app.legality = false;
                end
            else
                app.legality = false;
            end
        end


        % King Move
        if piece == "wk" || piece == "bk"
            app.legality = check_king(app,fromc,fromr,toc,tor);
        end
    %~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~%


    end
end


function valid_ud = check_updown(app,fromr,tor,toc)
% Purpose: check if the spaces between the orginal and destination square
% are empty for UP and DOWN movements

    valid_ud = true;

    % If piece moves up more than 1
    if tor < fromr && fromr-tor > 1
        n = fromr - tor;
        i = 1;
        while valid_ud == true && i<=(n-1)
            if app.board(tor + i, toc) ~= "0"
                valid_ud = false;
            end
            i = i + 1;
        end
    end

    % If piece moves down more than 1
    if fromr < tor && tor-fromr > 1
        n = tor - fromr;
        i = 1;
        while valid_ud == true &&  i<=(n-1)
            if app.board(tor - i,toc) ~= "0"
                valid_ud = false;
            end
            i = i + 1;
        end
    end

end


function valid_lr = check_sideside(app,fromc,toc,tor)
% Purpose: check if the spaces between the orginal and destination square
% are empty for LEFT and RIGHT movements

    valid_lr = true;

    % If piece moves left more than 1
    if toc < fromc && fromc-toc > 1
        n = fromc - toc;
        i = 1;
        while valid_lr == true && i<=(n-1)
            if app.board(tor,toc + i) ~= "0"
                valid_lr = false;
            end
            i = i + 1;
        end
    end

    % If piece moves right more than 1
    if fromc < toc && toc-fromc > 1
        n = toc - fromc;
        i = 1;
        while valid_lr == true &&  i<=(n-1)
            if app.board(tor,toc - i) ~= "0"
                valid_lr = false;
            end
            i = i + 1;
        end
    end

end


function valid_knight = check_knight(~,fromc,fromr,toc,tor)
% Purpose: check if the move that a knight is making is valid

delt_x = fromc-toc;
x = abs(delt_x);

delt_y = fromr-tor;
y = abs(delt_y);
    
    if (x == 1) && (y == 2)
        valid_knight = true;
    elseif (x == 2) && (y == 1)
        valid_knight = true;
    else
        valid_knight = false;
    end


end


function valid_king = check_king(app,fromc,fromr,toc,tor)
% Purpose: check if the move that the king is making is valid

delt_x = fromc-toc;
x = abs(delt_x);

delt_y = fromr-tor;
y = abs(delt_y);

    % Regular Move
    if (x<=1) && (y<=1)
        valid_king = true;
        if app.turn == 1
            app.whiteCastlePrivilege_L = false;
            app.whiteCastlePrivilege_R = false;
        elseif app.turn == 2
            app.blackCastlePrivilege_L = false;
            app.whiteCastlePrivilege_R = false;
        end
    else
        valid_king = false;
    end


    % Legality of White Castling
    if (app.turn == 1)
        % Castle left
        if (tor == 8) && (toc == 3) && (app.whiteCastlePrivilege_L == true)
            if (app.board(8,4) == "0") && (app.board(8,3) == "0") && (app.board(8,2) == "0") && (app.board(8,1) == "wr")
                app.whiteCastle = true;
                app.whiteCastlePrivilege_L = false;
                app.whiteCastlePrivilege_R = false;
                valid_king = true;
            end
        end
        % Castle right
        if (tor == 8) && (toc == 7) && (app.whiteCastlePrivilege_R == true)
            if (app.board(8,6) == "0") && (app.board(8,7) == "0") && (app.board(8,8) == "wr")
                app.whiteCastle = true;
                app.whiteCastlePrivilege_L = false;
                app.whiteCastlePrivilege_R = false;
                valid_king = true;
            end

        end
    end


    % Legality of Black Castling
    if (app.turn == 2)
        % Castle left
        if (tor == 1) && (toc == 3) && (app.blackCastlePrivilege_L == true)
            if (app.board(1,4) == "0") && (app.board(1,3) == "0") && (app.board(1,2) == "0") && (app.board(1,1) == "br")
                app.blackCastle = true;
                app.blackCastlePrivilege_L = false;
                app.blackCastlePrivilege_R = false;
                valid_king = true;
            end
        end
        % Castle right
        if (tor == 1) && (toc == 7) && (app.blackCastlePrivilege_R == true)
            if (app.board(1,6) == "0") && (app.board(1,7) == "0") && (app.board(1,8) == "br")
                app.blackCastle = true;
                app.blackCastlePrivilege_L = false;
                app.blackCastlePrivilege_R = false;
                valid_king = true;
            end

        end
    end

end


function valid_SE = check_SE(app,fromr,tor,toc)
% Purpose: Check if the spaces between the piece and the destination square
% are open
    valid_SE = true;
    n = abs(tor - fromr);
    i = 1;
    while valid_SE == true &&  i<=(n-1)
        if app.board(tor - i,toc - i) ~= "0"
            valid_SE = false;
        end
        i = i + 1;
    end
end


function valid_NE = check_NE(app,fromr,tor,toc)
% Purpose: Check if the spaces between the piece and the destination square
% are open
    valid_NE = true;
    n = abs(tor - fromr);
    i = 1;
    while valid_NE == true &&  i<=(n-1)
        if app.board(tor + i,toc - i) ~= "0"
            valid_NE = false;
        end
        i = i + 1;
    end
end


function valid_NW = check_NW(app,fromr,tor,toc)
% Purpose: Check if the spaces between the piece and the destination square
% are open
    valid_NW = true;
    n = abs(tor - fromr);
    i = 1;
    while valid_NW == true &&  i<=(n-1)
        if app.board(tor + i,toc + i) ~= "0"
            valid_NW = false;
        end
        i = i + 1;
    end
end


function valid_SW = check_SW(app,fromr,tor,toc)
% Purpose: Check if the spaces between the piece and the destination square
% are open
    valid_SW = true;
    n = abs(tor - fromr);
    i = 1;
    while valid_SW == true &&  i<=(n-1)
        if app.board(tor - i,toc + i) ~= "0"
            valid_SW = false;
        end
        i = i + 1;
    end
end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            setup_board(app);
            start_game(app);
            white_starts(app);
            white_color_starts(app);
            castle_privilege(app);

            app.column = [32 84 136 189 241 293 345 398];
            app.row = [33 85 138 190 242 294 347 399];

            % White Pieces
            app.ca{1,1} = app.white_rook_a;
            app.ca{2,1} = app.white_knight_b;
            app.ca{3,1} = app.white_bishop_c;
            app.ca{4,1} = app.white_queen;
            app.ca{5,1} = app.white_king;
            app.ca{6,1} = app.white_bishop_f;
            app.ca{7,1} = app.white_knight_g;
            app.ca{8,1} = app.white_rook_h;

            % White Pawns
            app.ca{1,2} = app.white_pawn_a;
            app.ca{2,2} = app.white_pawn_b;
            app.ca{3,2} = app.white_pawn_c;
            app.ca{4,2} = app.white_pawn_d;
            app.ca{5,2} = app.white_pawn_e;
            app.ca{6,2} = app.white_pawn_f;
            app.ca{7,2} = app.white_pawn_g;
            app.ca{8,2} = app.white_pawn_h;


            % Black Pieces
            app.ca{1,8} = app.black_rook_a;
            app.ca{2,8} = app.black_knight_b;
            app.ca{3,8} = app.black_bishop_c;
            app.ca{4,8} = app.black_queen;
            app.ca{5,8} = app.black_king;
            app.ca{6,8} = app.black_bishop_f;
            app.ca{7,8} = app.black_knight_g;
            app.ca{8,8} = app.black_rook_h;

            % Black Pawns
            app.ca{1,7} = app.black_pawn_a;
            app.ca{2,7} = app.black_pawn_b;
            app.ca{3,7} = app.black_pawn_c;
            app.ca{4,7} = app.black_pawn_d;
            app.ca{5,7} = app.black_pawn_e;
            app.ca{6,7} = app.black_pawn_f;
            app.ca{7,7} = app.black_pawn_g;
            app.ca{8,7} = app.black_pawn_h;
            
        end

        % Button pushed function: MoveButton
        function MoveButtonPushed(app, event)
if app.game == 0
    
    % Checks for valid notation
    checkValidCoordinate(app);
    checkValidDestination(app);
    checkRepetition(app);        


    if app.valid == true

        % Reads the piece
        app.alpha_num = read_which_piece(app);
        fromr = coordinate_to_row(app);
        fromc = coordinate_to_column(app);
    
        % Reads destination
        app.alpha_num = read_destination(app);
        tor = coordinate_to_row(app);
        toc = coordinate_to_column(app);
    
        % Ensures Legality
        checkLegal(app,fromr,fromc,tor,toc);

        if app.legality == true
            % Move piece and change turn
            movePiece(app,fromr,fromc,tor,toc);
            changeTurn(app);
            changeTurnColor(app);
            change_whoMovesLabel(app);
            clearFields(app);
            clearFeedback(app);
            showWin(app);
        else
            % Display that the move was illegal / DOESNT change turns
            dispIllegalMove(app);
            clearFields(app);
        end

    else
        dispInvalidMove(app);
        clearFields(app);
    end

end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PieceCoordinateEditFieldLabel = uilabel(app.UIFigure);
            app.PieceCoordinateEditFieldLabel.Text = 'Piece Coordinate';
            app.PieceCoordinateEditField = uieditfield(app.UIFigure, 'text');
            app.MoveButton = uibutton(app.UIFigure, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Text = 'Move';
            app.DestinationEditFieldLabel = uilabel(app.UIFigure);
            app.DestinationEditFieldLabel.Text = 'Destination';
            app.DestinationEditField = uieditfield(app.UIFigure, 'text');
            app.chessboard = uiimage(app.UIFigure);
            app.chessboard.ImageSource = 'Chessboard.jpg';
            app.WhoMovesLabel = uilabel(app.UIFigure);
            app.WhoMovesLabel.Text = 'White to Move';
            app.white_pawn_h = uiimage(app.UIFigure);
            app.white_pawn_h.ImageSource = fullfile(pathToMLAPP, 'Piece_images', 'wp.png');
            app.white_pawn_g = uiimage(app.UIFigure);
            app.white_pawn_g.ImageSource = fullfile(pathToMLAPP, 'Piece_images', 'wp.png');
            app.white_pawn_f = uiimage(app.UIFigure);
            app.white_pawn_f.ImageSource = fullfile(pathToMLAPP, 'Piece_images', 'wp.png');
            app.white_pawn_e = uiimage(app.UIFigure);
            app.white_pawn_e.ImageSource = fullfile(pathToMLAPP, 'Piece_images', 'wp.png');
            app.white_pawn_d = uiimage(app.UIFigure);
            app.white_pawn_d.ImageSource = fullfile(pathToMLAPP, 'Piece_images', 'wp.png');
            app.white_pawn_c = uiimage(app.UIFigure);
            app.white_pawn_c.ImageSource = fullfile(pathToMLAPP, 'Piece_images', 'wp.png');
            app.white_pawn_b = uiimage(app.UIFigure);
            app.white_pawn_b.ImageSource = fullfile(pathToMLAPP, 'Piece_images', 'wp.png');
            app.white_pawn_a = uiimage(app.UIFigure);
            app.white_pawn_a.ImageSource = fullfile(pathToMLAPP, 'Piece_images', 'wp.png');
            app.white_rook_h = uiimage(app.UIFigure);
            app.white_rook_h.ImageSource = fullfile(pathToMLAPP, 'Piece_images', 'wr.png');
            app.white_knight_g = uiimage(app.UIFigure);
            app.white_knight_g.ImageSource = 'wn.png';
            app.white_bishop_f = uiimage(app.UIFigure);
            app.white_bishop_f.ImageSource = fullfile(pathToMLAPP, 'wb.png');
            app.white_queen = uiimage(app.UIFigure);
            app.white_queen.ImageSource = fullfile(pathToMLAPP, 'wq.png');
            app.white_king = uiimage(app.UIFigure);
            app.white_king.ImageSource = 'wk.png';
            app.white_bishop_c = uiimage(app.UIFigure);
            app.white_bishop_c.ImageSource = fullfile(pathToMLAPP, 'wb.png');
            app.white_knight_b = uiimage(app.UIFigure);
            app.white_knight_b.ImageSource = 'wn.png';
            app.white_rook_a = uiimage(app.UIFigure);
            app.white_rook_a.ImageSource = fullfile(pathToMLAPP, 'Piece_images', 'wr.png');
            app.black_pawn_h = uiimage(app.UIFigure);
            app.black_pawn_h.ImageSource = 'bp.png';
            app.black_pawn_g = uiimage(app.UIFigure);
            app.black_pawn_g.ImageSource = 'bp.png';
            app.black_pawn_f = uiimage(app.UIFigure);
            app.black_pawn_f.ImageSource = 'bp.png';
            app.black_pawn_e = uiimage(app.UIFigure);
            app.black_pawn_e.ImageSource = 'bp.png';
            app.black_pawn_d = uiimage(app.UIFigure);
            app.black_pawn_d.ImageSource = 'bp.png';
            app.black_pawn_c = uiimage(app.UIFigure);
            app.black_pawn_c.ImageSource = 'bp.png';
            app.black_pawn_b = uiimage(app.UIFigure);
            app.black_pawn_b.ImageSource = 'bp.png';
            app.black_pawn_a = uiimage(app.UIFigure);
            app.black_pawn_a.ImageSource = 'bp.png';
            app.black_rook_h = uiimage(app.UIFigure);
            app.black_rook_h.ImageSource = fullfile(pathToMLAPP, 'br.png');
            app.black_knight_g = uiimage(app.UIFigure);
            app.black_knight_g.ImageSource = fullfile(pathToMLAPP, 'bn.png');
            app.black_bishop_f = uiimage(app.UIFigure);
            app.black_bishop_f.ImageSource = fullfile(pathToMLAPP, 'bb.png');
            app.black_king = uiimage(app.UIFigure);
            app.black_king.ImageSource = fullfile(pathToMLAPP, 'bk.png');
            app.black_queen = uiimage(app.UIFigure);
            app.black_queen.ImageSource = fullfile(pathToMLAPP, 'bq.png');
            app.black_bishop_c = uiimage(app.UIFigure);
            app.black_bishop_c.ImageSource = fullfile(pathToMLAPP, 'bb.png');
            app.black_knight_b = uiimage(app.UIFigure);
            app.black_knight_b.ImageSource = fullfile(pathToMLAPP, 'bn.png');
            app.black_rook_a = uiimage(app.UIFigure);
            app.black_rook_a.ImageSource = fullfile(pathToMLAPP, 'br.png');
            app.column_coordinates = uilabel(app.UIFigure);
            app.column_coordinates.Text = 'A         B          C         D         E          F         G         H';
            app.row_coordinates = uilabel(app.UIFigure);
            app.row_coordinates.Text = {'8'; ''; ''; '7'; ''; ''; '6'; ''; ''; '5'; ''; ''; '4'; ''; ''; '3'; ''; ''; '2'; ''; ''; '1'};
            app.PromotionDropDownLabel = uilabel(app.UIFigure);
            app.PromotionDropDownLabel.Text = 'Promotion';
            app.PromotionDropDown = uidropdown(app.UIFigure);
            app.PromotionDropDown.Items = {'Queen', 'Rook', 'Bishop', 'Knight'};
            app.PromotionDropDown.Value = 'Queen';
            app.moveFeedbackLabel = uilabel(app.UIFigure);
            app.moveFeedbackLabel.Text = {'First to capture the'; 'opponent''s king wins!'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = chessmatch

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end